classdef Sudoku_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        ExpertTab             matlab.ui.container.Tab
        SOLVEButton           matlab.ui.control.Button
        InputYourSudokuPanel  matlab.ui.container.Panel
        Panel_9               matlab.ui.container.Panel
        GridLayout_9          matlab.ui.container.GridLayout
        EditField_79          matlab.ui.control.NumericEditField
        EditField_80          matlab.ui.control.NumericEditField
        EditField_71          matlab.ui.control.NumericEditField
        EditField_70          matlab.ui.control.NumericEditField
        EditField_61          matlab.ui.control.NumericEditField
        EditField_62          matlab.ui.control.NumericEditField
        EditField_63          matlab.ui.control.NumericEditField
        EditField_72          matlab.ui.control.NumericEditField
        EditField_81          matlab.ui.control.NumericEditField
        Panel_8               matlab.ui.container.Panel
        GridLayout_8          matlab.ui.container.GridLayout
        EditField_25          matlab.ui.control.NumericEditField
        EditField_26          matlab.ui.control.NumericEditField
        EditField_17          matlab.ui.control.NumericEditField
        EditField_16          matlab.ui.control.NumericEditField
        EditField_7           matlab.ui.control.NumericEditField
        EditField_8           matlab.ui.control.NumericEditField
        EditField_9           matlab.ui.control.NumericEditField
        EditField_18          matlab.ui.control.NumericEditField
        EditField_27          matlab.ui.control.NumericEditField
        Panel_7               matlab.ui.container.Panel
        GridLayout_7          matlab.ui.container.GridLayout
        EditField_52          matlab.ui.control.NumericEditField
        EditField_53          matlab.ui.control.NumericEditField
        EditField_44          matlab.ui.control.NumericEditField
        EditField_43          matlab.ui.control.NumericEditField
        EditField_34          matlab.ui.control.NumericEditField
        EditField_35          matlab.ui.control.NumericEditField
        EditField_36          matlab.ui.control.NumericEditField
        EditField_45          matlab.ui.control.NumericEditField
        EditField_54          matlab.ui.control.NumericEditField
        Panel_6               matlab.ui.container.Panel
        GridLayout_6          matlab.ui.container.GridLayout
        EditField_76          matlab.ui.control.NumericEditField
        EditField_77          matlab.ui.control.NumericEditField
        EditField_68          matlab.ui.control.NumericEditField
        EditField_67          matlab.ui.control.NumericEditField
        EditField_58          matlab.ui.control.NumericEditField
        EditField_59          matlab.ui.control.NumericEditField
        EditField_60          matlab.ui.control.NumericEditField
        EditField_69          matlab.ui.control.NumericEditField
        EditField_78          matlab.ui.control.NumericEditField
        Panel_5               matlab.ui.container.Panel
        GridLayout_5          matlab.ui.container.GridLayout
        EditField_22          matlab.ui.control.NumericEditField
        EditField_23          matlab.ui.control.NumericEditField
        EditField_14          matlab.ui.control.NumericEditField
        EditField_13          matlab.ui.control.NumericEditField
        EditField_4           matlab.ui.control.NumericEditField
        EditField_5           matlab.ui.control.NumericEditField
        EditField_6           matlab.ui.control.NumericEditField
        EditField_15          matlab.ui.control.NumericEditField
        EditField_24          matlab.ui.control.NumericEditField
        Panel_4               matlab.ui.container.Panel
        GridLayout_4          matlab.ui.container.GridLayout
        EditField_49          matlab.ui.control.NumericEditField
        EditField_50          matlab.ui.control.NumericEditField
        EditField_41          matlab.ui.control.NumericEditField
        EditField_40          matlab.ui.control.NumericEditField
        EditField_31          matlab.ui.control.NumericEditField
        EditField_32          matlab.ui.control.NumericEditField
        EditField_33          matlab.ui.control.NumericEditField
        EditField_42          matlab.ui.control.NumericEditField
        EditField_51          matlab.ui.control.NumericEditField
        Panel_3               matlab.ui.container.Panel
        GridLayout_3          matlab.ui.container.GridLayout
        EditField_73          matlab.ui.control.NumericEditField
        EditField_74          matlab.ui.control.NumericEditField
        EditField_65          matlab.ui.control.NumericEditField
        EditField_64          matlab.ui.control.NumericEditField
        EditField_55          matlab.ui.control.NumericEditField
        EditField_56          matlab.ui.control.NumericEditField
        EditField_57          matlab.ui.control.NumericEditField
        EditField_66          matlab.ui.control.NumericEditField
        EditField_75          matlab.ui.control.NumericEditField
        Panel_2               matlab.ui.container.Panel
        GridLayout_2          matlab.ui.container.GridLayout
        EditField_46          matlab.ui.control.NumericEditField
        EditField_47          matlab.ui.control.NumericEditField
        EditField_38          matlab.ui.control.NumericEditField
        EditField_37          matlab.ui.control.NumericEditField
        EditField_28          matlab.ui.control.NumericEditField
        EditField_29          matlab.ui.control.NumericEditField
        EditField_30          matlab.ui.control.NumericEditField
        EditField_39          matlab.ui.control.NumericEditField
        EditField_48          matlab.ui.control.NumericEditField
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        EditField_21          matlab.ui.control.NumericEditField
        EditField_12          matlab.ui.control.NumericEditField
        EditField_3           matlab.ui.control.NumericEditField
        EditField_2           matlab.ui.control.NumericEditField
        EditField_11          matlab.ui.control.NumericEditField
        EditField_20          matlab.ui.control.NumericEditField
        EditField_19          matlab.ui.control.NumericEditField
        EditField_10          matlab.ui.control.NumericEditField
        EditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        gr0 = zeros(9); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value~=0
                app.EditField.BackgroundColor = [0.6,1,1];
                app.gr0(1) = value;
            end
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            if value~=0
                app.EditField_10.BackgroundColor = [0.6,1,1];
                app.gr0(10) = value;
            end
        end

        % Value changed function: EditField_19
        function EditField_19ValueChanged(app, event)
            value = app.EditField_19.Value;
            if value~=0
                app.EditField_19.BackgroundColor = [0.6,1,1];
                app.gr0(19) = value;
            end
        end

        % Value changed function: EditField_20
        function EditField_20ValueChanged(app, event)
            value = app.EditField_20.Value;
            if value~=0
                app.EditField_20.BackgroundColor = [0.6,1,1];
                app.gr0(20) = value;
            end
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            if value~=0
                app.EditField_11.BackgroundColor = [0.6,1,1];
                app.gr0(11) = value;
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if value~=0
                app.EditField_2.BackgroundColor = [0.6,1,1];
                app.gr0(2) = value;
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            if value~=0
                app.EditField_3.BackgroundColor = [0.6,1,1];
                app.gr0(3) = value;
            end
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            value = app.EditField_12.Value;
            if value~=0
                app.EditField_12.BackgroundColor = [0.6,1,1];
                app.gr0(12) = value;
            end
        end

        % Value changed function: EditField_21
        function EditField_21ValueChanged(app, event)
            value = app.EditField_21.Value;
            if value~=0
                app.EditField_21.BackgroundColor = [0.6,1,1];
                app.gr0(21) = value;
            end
        end

        % Value changed function: EditField_28
        function EditField_28ValueChanged(app, event)
            value = app.EditField_28.Value;
            if value~=0
                app.EditField_28.BackgroundColor = [0.6,1,1];
                app.gr0(28) = value;
            end
        end

        % Value changed function: EditField_37
        function EditField_37ValueChanged(app, event)
            value = app.EditField_37.Value;
            if value~=0
                app.EditField_37.BackgroundColor = [0.6,1,1];
                app.gr0(37) = value;
            end
        end

        % Value changed function: EditField_46
        function EditField_46ValueChanged(app, event)
            value = app.EditField_46.Value;
            if value~=0
                app.EditField_46.BackgroundColor = [0.6,1,1];
                app.gr0(46) = value;
            end
        end

        % Value changed function: EditField_47
        function EditField_47ValueChanged(app, event)
            value = app.EditField_47.Value;
            if value~=0
                app.EditField_47.BackgroundColor = [0.6,1,1];
                app.gr0(47) = value;
            end
        end

        % Value changed function: EditField_38
        function EditField_38ValueChanged(app, event)
            value = app.EditField_38.Value;
            if value~=0
                app.EditField_38.BackgroundColor = [0.6,1,1];
                app.gr0(38) = value;
            end
        end

        % Value changed function: EditField_29
        function EditField_29ValueChanged(app, event)
            value = app.EditField_29.Value;
            if value~=0
                app.EditField_29.BackgroundColor = [0.6,1,1];
                app.gr0(29) = value;
            end
        end

        % Value changed function: EditField_30
        function EditField_30ValueChanged(app, event)
            value = app.EditField_30.Value;
            if value~=0
                app.EditField_30.BackgroundColor = [0.6,1,1];
                app.gr0(30) = value;
            end
        end

        % Value changed function: EditField_39
        function EditField_39ValueChanged(app, event)
            value = app.EditField_39.Value;
            if value~=0
                app.EditField_39.BackgroundColor = [0.6,1,1];
                app.gr0(39) = value;
            end
        end

        % Value changed function: EditField_48
        function EditField_48ValueChanged(app, event)
            value = app.EditField_48.Value;
            if value~=0
                app.EditField_48.BackgroundColor = [0.6,1,1];
                app.gr0(48) = value;
            end
        end

        % Value changed function: EditField_55
        function EditField_55ValueChanged(app, event)
            value = app.EditField_55.Value;
            if value~=0
                app.EditField_55.BackgroundColor = [0.6,1,1];
                app.gr0(55) = value;
            end
        end

        % Value changed function: EditField_64
        function EditField_64ValueChanged(app, event)
            value = app.EditField_64.Value;
            if value~=0
                app.EditField_64.BackgroundColor = [0.6,1,1];
                app.gr0(64) = value;
            end
        end

        % Value changed function: EditField_73
        function EditField_73ValueChanged(app, event)
            value = app.EditField_73.Value;
            if value~=0
                app.EditField_73.BackgroundColor = [0.6,1,1];
                app.gr0(73) = value;
            end
        end

        % Value changed function: EditField_74
        function EditField_74ValueChanged(app, event)
            value = app.EditField_74.Value;
            if value~=0
                app.EditField_74.BackgroundColor = [0.6,1,1];
                app.gr0(74) = value;
            end
        end

        % Value changed function: EditField_65
        function EditField_65ValueChanged(app, event)
            value = app.EditField_65.Value;
            if value~=0
                app.EditField_65.BackgroundColor = [0.6,1,1];
                app.gr0(65) = value;
            end
        end

        % Value changed function: EditField_56
        function EditField_56ValueChanged(app, event)
            value = app.EditField_56.Value;
            if value~=0
                app.EditField_56.BackgroundColor = [0.6,1,1];
                app.gr0(56) = value;
            end
        end

        % Value changed function: EditField_57
        function EditField_57ValueChanged(app, event)
            value = app.EditField_57.Value;
            if value~=0
                app.EditField_57.BackgroundColor = [0.6,1,1];
                app.gr0(57) = value;
            end
        end

        % Value changed function: EditField_66
        function EditField_66ValueChanged(app, event)
            value = app.EditField_66.Value;
            if value~=0
                app.EditField_66.BackgroundColor = [0.6,1,1];
                app.gr0(66) = value;
            end
        end

        % Value changed function: EditField_75
        function EditField_75ValueChanged(app, event)
            value = app.EditField_75.Value;
            if value~=0
                app.EditField_75.BackgroundColor = [0.6,1,1];
                app.gr0(75) = value;
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            if value~=0
                app.EditField_4.BackgroundColor = [0.6,1,1];
                app.gr0(4) = value;
            end
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            if value~=0
                app.EditField_13.BackgroundColor = [0.6,1,1];
                app.gr0(13) = value;
            end
        end

        % Value changed function: EditField_22
        function EditField_22ValueChanged(app, event)
            value = app.EditField_22.Value;
            if value~=0
                app.EditField_22.BackgroundColor = [0.6,1,1];
                app.gr0(22) = value;
            end
        end

        % Value changed function: EditField_23
        function EditField_23ValueChanged(app, event)
            value = app.EditField_23.Value;
            if value~=0
                app.EditField_23.BackgroundColor = [0.6,1,1];
                app.gr0(23) = value;
            end
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            if value~=0
                app.EditField_14.BackgroundColor = [0.6,1,1];
                app.gr0(14) = value;
            end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            if value~=0
                app.EditField_5.BackgroundColor = [0.6,1,1];
                app.gr0(5) = value;
            end
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            if value~=0
                app.EditField_6.BackgroundColor = [0.6,1,1];
                app.gr0(6) = value;
            end
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            if value~=0
                app.EditField_15.BackgroundColor = [0.6,1,1];
                app.gr0(15) = value;
            end
        end

        % Value changed function: EditField_24
        function EditField_24ValueChanged(app, event)
            value = app.EditField_24.Value;
            if value~=0
                app.EditField_24.BackgroundColor = [0.6,1,1];
                app.gr0(24) = value;
            end
        end

        % Value changed function: EditField_31
        function EditField_31ValueChanged(app, event)
            value = app.EditField_31.Value;
            if value~=0
                app.EditField_31.BackgroundColor = [0.6,1,1];
                app.gr0(31) = value;
            end
        end

        % Value changed function: EditField_40
        function EditField_40ValueChanged(app, event)
            value = app.EditField_40.Value;
            if value~=0
                app.EditField_40.BackgroundColor = [0.6,1,1];
                app.gr0(40) = value;
            end
        end

        % Value changed function: EditField_49
        function EditField_49ValueChanged(app, event)
            value = app.EditField_49.Value;
            if value~=0
                app.EditField_49.BackgroundColor = [0.6,1,1];
                app.gr0(49) = value;
            end
        end

        % Value changed function: EditField_50
        function EditField_50ValueChanged(app, event)
            value = app.EditField_50.Value;
            if value~=0
                app.EditField_50.BackgroundColor = [0.6,1,1];
                app.gr0(50) = value;
            end
        end

        % Value changed function: EditField_41
        function EditField_41ValueChanged(app, event)
            value = app.EditField_41.Value;
            if value~=0
                app.EditField_41.BackgroundColor = [0.6,1,1];
                app.gr0(41) = value;
            end
        end

        % Value changed function: EditField_32
        function EditField_32ValueChanged(app, event)
            value = app.EditField_32.Value;
            if value~=0
                app.EditField_32.BackgroundColor = [0.6,1,1];
                app.gr0(32) = value;
            end
        end

        % Value changed function: EditField_33
        function EditField_33ValueChanged(app, event)
            value = app.EditField_33.Value;
            if value~=0
                app.EditField_33.BackgroundColor = [0.6,1,1];
                app.gr0(33) = value;
            end
        end

        % Value changed function: EditField_42
        function EditField_42ValueChanged(app, event)
            value = app.EditField_42.Value;
            if value~=0
                app.EditField_42.BackgroundColor = [0.6,1,1];
                app.gr0(42) = value;
            end
        end

        % Value changed function: EditField_51
        function EditField_51ValueChanged(app, event)
            value = app.EditField_51.Value;
            if value~=0
                app.EditField_51.BackgroundColor = [0.6,1,1];
                app.gr0(51) = value;
            end
        end

        % Value changed function: EditField_58
        function EditField_58ValueChanged(app, event)
            value = app.EditField_58.Value;
            if value~=0
                app.EditField_58.BackgroundColor = [0.6,1,1];
                app.gr0(58) = value;
            end
        end

        % Value changed function: EditField_67
        function EditField_67ValueChanged(app, event)
            value = app.EditField_67.Value;
            if value~=0
                app.EditField_67.BackgroundColor = [0.6,1,1];
                app.gr0(67) = value;
            end
        end

        % Value changed function: EditField_76
        function EditField_76ValueChanged(app, event)
            value = app.EditField_76.Value;
            if value~=0
                app.EditField_76.BackgroundColor = [0.6,1,1];
                app.gr0(76) = value;
            end
        end

        % Value changed function: EditField_77
        function EditField_77ValueChanged(app, event)
            value = app.EditField_77.Value;
            if value~=0
                app.EditField_77.BackgroundColor = [0.6,1,1];
                app.gr0(77) = value;
            end
        end

        % Value changed function: EditField_68
        function EditField_68ValueChanged(app, event)
            value = app.EditField_68.Value;
            if value~=0
                app.EditField_68.BackgroundColor = [0.6,1,1];
                app.gr0(68) = value;
            end
        end

        % Value changed function: EditField_59
        function EditField_59ValueChanged(app, event)
            value = app.EditField_59.Value;
            if value~=0
                app.EditField_59.BackgroundColor = [0.6,1,1];
                app.gr0(59) = value;
            end
        end

        % Value changed function: EditField_60
        function EditField_60ValueChanged(app, event)
            value = app.EditField_60.Value;
            if value~=0
                app.EditField_60.BackgroundColor = [0.6,1,1];
                app.gr0(60) = value;
            end
        end

        % Value changed function: EditField_69
        function EditField_69ValueChanged(app, event)
            value = app.EditField_69.Value;
            if value~=0
                app.EditField_69.BackgroundColor = [0.6,1,1];
                app.gr0(69) = value;
            end
        end

        % Value changed function: EditField_78
        function EditField_78ValueChanged(app, event)
            value = app.EditField_78.Value;
            if value~=0
                app.EditField_78.BackgroundColor = [0.6,1,1];
                app.gr0(78) = value;
            end
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            if value~=0
                app.EditField_7.BackgroundColor = [0.6,1,1];
                app.gr0(7) = value;
            end
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            value = app.EditField_16.Value;
            if value~=0
                app.EditField_16.BackgroundColor = [0.6,1,1];
                app.gr0(16) = value;
            end
        end

        % Value changed function: EditField_25
        function EditField_25ValueChanged(app, event)
            value = app.EditField_25.Value;
            if value~=0
                app.EditField_25.BackgroundColor = [0.6,1,1];
                app.gr0(25) = value;
            end
        end

        % Value changed function: EditField_26
        function EditField_26ValueChanged(app, event)
            value = app.EditField_26.Value;
            if value~=0
                app.EditField_26.BackgroundColor = [0.6,1,1];
                app.gr0(26) = value;
            end
        end

        % Value changed function: EditField_17
        function EditField_17ValueChanged(app, event)
            value = app.EditField_17.Value;
            if value~=0
                app.EditField_17.BackgroundColor = [0.6,1,1];
                app.gr0(17) = value;
            end
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            if value~=0
                app.EditField_8.BackgroundColor = [0.6,1,1];
                app.gr0(8) = value;
            end
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            if value~=0
                app.EditField_9.BackgroundColor = [0.6,1,1];
                app.gr0(9) = value;
            end
        end

        % Value changed function: EditField_18
        function EditField_18ValueChanged(app, event)
            value = app.EditField_18.Value;
            if value~=0
                app.EditField_18.BackgroundColor = [0.6,1,1];
                app.gr0(18) = value;
            end
        end

        % Value changed function: EditField_27
        function EditField_27ValueChanged(app, event)
            value = app.EditField_27.Value;
            if value~=0
                app.EditField_27.BackgroundColor = [0.6,1,1];
                app.gr0(27) = value;
            end
        end

        % Value changed function: EditField_34
        function EditField_34ValueChanged(app, event)
            value = app.EditField_34.Value;
            if value~=0
                app.EditField_34.BackgroundColor = [0.6,1,1];
                app.gr0(34) = value;
            end
        end

        % Value changed function: EditField_43
        function EditField_43ValueChanged(app, event)
            value = app.EditField_43.Value;
            if value~=0
                app.EditField_43.BackgroundColor = [0.6,1,1];
                app.gr0(43) = value;
            end
        end

        % Value changed function: EditField_52
        function EditField_52ValueChanged(app, event)
            value = app.EditField_52.Value;
            if value~=0
                app.EditField_52.BackgroundColor = [0.6,1,1];
                app.gr0(52) = value;
            end
        end

        % Value changed function: EditField_53
        function EditField_53ValueChanged(app, event)
            value = app.EditField_53.Value;
            if value~=0
                app.EditField_53.BackgroundColor = [0.6,1,1];
                app.gr0(53) = value;
            end
        end

        % Value changed function: EditField_44
        function EditField_44ValueChanged(app, event)
            value = app.EditField_44.Value;
            if value~=0
                app.EditField_44.BackgroundColor = [0.6,1,1];
                app.gr0(44) = value;
            end
        end

        % Value changed function: EditField_35
        function EditField_35ValueChanged(app, event)
            value = app.EditField_35.Value;
            if value~=0
                app.EditField_35.BackgroundColor = [0.6,1,1];
                app.gr0(35) = value;
            end
        end

        % Value changed function: EditField_36
        function EditField_36ValueChanged(app, event)
            value = app.EditField_36.Value;
            if value~=0
                app.EditField_36.BackgroundColor = [0.6,1,1];
                app.gr0(36) = value;
            end
        end

        % Value changed function: EditField_45
        function EditField_45ValueChanged(app, event)
            value = app.EditField_45.Value;
            if value~=0
                app.EditField_45.BackgroundColor = [0.6,1,1];
                app.gr0(45) = value;
            end
        end

        % Value changed function: EditField_54
        function EditField_54ValueChanged(app, event)
            value = app.EditField_54.Value;
            if value~=0
                app.EditField_54.BackgroundColor = [0.6,1,1];
                app.gr0(54) = value;
            end
        end

        % Value changed function: EditField_61
        function EditField_61ValueChanged(app, event)
            value = app.EditField_61.Value;
            if value~=0
                app.EditField_61.BackgroundColor = [0.6,1,1];
                app.gr0(61) = value;
            end
        end

        % Value changed function: EditField_70
        function EditField_70ValueChanged(app, event)
            value = app.EditField_70.Value;
            if value~=0
                app.EditField_70.BackgroundColor = [0.6,1,1];
                app.gr0(70) = value;
            end
        end

        % Value changed function: EditField_79
        function EditField_79ValueChanged(app, event)
            value = app.EditField_79.Value;
            if value~=0
                app.EditField_79.BackgroundColor = [0.6,1,1];
                app.gr0(79) = value;
            end
        end

        % Value changed function: EditField_80
        function EditField_80ValueChanged(app, event)
            value = app.EditField_80.Value;
            if value~=0
                app.EditField_80.BackgroundColor = [0.6,1,1];
                app.gr0(80) = value;
            end
        end

        % Value changed function: EditField_71
        function EditField_71ValueChanged(app, event)
            value = app.EditField_71.Value;
            if value~=0
                app.EditField_71.BackgroundColor = [0.6,1,1];
                app.gr0(71) = value;
            end
        end

        % Value changed function: EditField_62
        function EditField_62ValueChanged(app, event)
            value = app.EditField_62.Value;
            if value~=0
                app.EditField_62.BackgroundColor = [0.6,1,1];
                app.gr0(62) = value;
            end
        end

        % Value changed function: EditField_63
        function EditField_63ValueChanged(app, event)
            value = app.EditField_63.Value;
            if value~=0
                app.EditField_63.BackgroundColor = [0.6,1,1];
                app.gr0(63) = value;
            end
        end

        % Value changed function: EditField_72
        function EditField_72ValueChanged(app, event)
            value = app.EditField_72.Value;
            if value~=0
                app.EditField_72.BackgroundColor = [0.6,1,1];
                app.gr0(72) = value;
            end
        end

        % Value changed function: EditField_81
        function EditField_81ValueChanged(app, event)
            value = app.EditField_81.Value;
            if value~=0
                app.EditField_81.BackgroundColor = [0.6,1,1];
                app.gr0(81) = value;
            end
        end

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)
            % plot(app.gr0)
            grf = sudoku_exp_final(app.gr0);
            gr_green = grf - app.gr0;
            app.EditField.Value     = grf( 1 );
            app.EditField_2.Value   = grf( 2 );
            app.EditField_3.Value   = grf( 3 );
            app.EditField_4.Value   = grf( 4 );
            app.EditField_5.Value   = grf( 5 );
            app.EditField_6.Value   = grf( 6 );
            app.EditField_7.Value   = grf( 7 );
            app.EditField_8.Value   = grf( 8 );
            app.EditField_9.Value   = grf( 9 );
            app.EditField_10.Value  = grf( 10 );
            app.EditField_11.Value  = grf( 11 );
            app.EditField_12.Value  = grf( 12 );
            app.EditField_13.Value  = grf( 13 );
            app.EditField_14.Value  = grf( 14 );
            app.EditField_15.Value  = grf( 15 );
            app.EditField_16.Value  = grf( 16 );
            app.EditField_17.Value  = grf( 17 );
            app.EditField_18.Value  = grf( 18 );
            app.EditField_19.Value  = grf( 19 );
            app.EditField_20.Value  = grf( 20 );
            app.EditField_21.Value  = grf( 21 );
            app.EditField_22.Value  = grf( 22 );
            app.EditField_23.Value  = grf( 23 );
            app.EditField_24.Value  = grf( 24 );
            app.EditField_25.Value  = grf( 25 );
            app.EditField_26.Value  = grf( 26 );
            app.EditField_27.Value  = grf( 27 );
            app.EditField_28.Value  = grf( 28 );
            app.EditField_29.Value  = grf( 29 );
            app.EditField_30.Value  = grf( 30 );
            app.EditField_31.Value  = grf( 31 );
            app.EditField_32.Value  = grf( 32 );
            app.EditField_33.Value  = grf( 33 );
            app.EditField_34.Value  = grf( 34 );
            app.EditField_35.Value  = grf( 35 );
            app.EditField_36.Value  = grf( 36 );
            app.EditField_37.Value  = grf( 37 );
            app.EditField_38.Value  = grf( 38 );
            app.EditField_39.Value  = grf( 39 );
            app.EditField_40.Value  = grf( 40 );
            app.EditField_41.Value  = grf( 41 );
            app.EditField_42.Value  = grf( 42 );
            app.EditField_43.Value  = grf( 43 );
            app.EditField_44.Value  = grf( 44 );
            app.EditField_45.Value  = grf( 45 );
            app.EditField_46.Value  = grf( 46 );
            app.EditField_47.Value  = grf( 47 );
            app.EditField_48.Value  = grf( 48 );
            app.EditField_49.Value  = grf( 49 );
            app.EditField_50.Value  = grf( 50 );
            app.EditField_51.Value  = grf( 51 );
            app.EditField_52.Value  = grf( 52 );
            app.EditField_53.Value  = grf( 53 );
            app.EditField_54.Value  = grf( 54 );
            app.EditField_55.Value  = grf( 55 );
            app.EditField_56.Value  = grf( 56 );
            app.EditField_57.Value  = grf( 57 );
            app.EditField_58.Value  = grf( 58 );
            app.EditField_59.Value  = grf( 59 );
            app.EditField_60.Value  = grf( 60 );
            app.EditField_61.Value  = grf( 61 );
            app.EditField_62.Value  = grf( 62 );
            app.EditField_63.Value  = grf( 63 );
            app.EditField_64.Value  = grf( 64 );
            app.EditField_65.Value  = grf( 65 );
            app.EditField_66.Value  = grf( 66 );
            app.EditField_67.Value  = grf( 67 );
            app.EditField_68.Value  = grf( 68 );
            app.EditField_69.Value  = grf( 69 );
            app.EditField_70.Value  = grf( 70 );
            app.EditField_71.Value  = grf( 71 );
            app.EditField_72.Value  = grf( 72 );
            app.EditField_73.Value  = grf( 73 );
            app.EditField_74.Value  = grf( 74 );
            app.EditField_75.Value  = grf( 75 );
            app.EditField_76.Value  = grf( 76 );
            app.EditField_77.Value  = grf( 77 );
            app.EditField_78.Value  = grf( 78 );
            app.EditField_79.Value  = grf( 79 );
            app.EditField_80.Value  = grf( 80 );
            app.EditField_81.Value  = grf( 81 );
            if gr_green(1)>0
                app.EditField.BackgroundColor = [0,1,0];
            end
            if gr_green(2)>0
                app.EditField_2.BackgroundColor = [0,1,0];
            end
            if gr_green(3)>0
                app.EditField_3.BackgroundColor = [0,1,0];
            end
            if gr_green(4)>0
                app.EditField_4.BackgroundColor = [0,1,0];
            end
            if gr_green(5)>0
                app.EditField_5.BackgroundColor = [0,1,0];
            end
            if gr_green(6)>0
                app.EditField_6.BackgroundColor = [0,1,0];
            end
            if gr_green(7)>0
                app.EditField_7.BackgroundColor = [0,1,0];
            end
            if gr_green(8)>0
                app.EditField_8.BackgroundColor = [0,1,0];
            end
            if gr_green(9)>0
                app.EditField_9.BackgroundColor = [0,1,0];
            end
            if gr_green(10)>0
                app.EditField_10.BackgroundColor = [0,1,0];
            end
            if gr_green(11)>0
                app.EditField_11.BackgroundColor = [0,1,0];
            end
            if gr_green(12)>0
                app.EditField_12.BackgroundColor = [0,1,0];
            end
            if gr_green(13)>0
                app.EditField_13.BackgroundColor = [0,1,0];
            end
            if gr_green(14)>0
                app.EditField_14.BackgroundColor = [0,1,0];
            end
            if gr_green(15)>0
                app.EditField_15.BackgroundColor = [0,1,0];
            end
            if gr_green(16)>0
                app.EditField_16.BackgroundColor = [0,1,0];
            end
            if gr_green(17)>0
                app.EditField_17.BackgroundColor = [0,1,0];
            end
            if gr_green(18)>0
                app.EditField_18.BackgroundColor = [0,1,0];
            end
            if gr_green(19)>0
                app.EditField_19.BackgroundColor = [0,1,0];
            end
            if gr_green(20)>0
                app.EditField_20.BackgroundColor = [0,1,0];
            end
            if gr_green(21)>0
                app.EditField_21.BackgroundColor = [0,1,0];
            end
            if gr_green(22)>0
                app.EditField_22.BackgroundColor = [0,1,0];
            end
            if gr_green(23)>0
                app.EditField_23.BackgroundColor = [0,1,0];
            end
            if gr_green(24)>0
                app.EditField_24.BackgroundColor = [0,1,0];
            end
            if gr_green(25)>0
                app.EditField_25.BackgroundColor = [0,1,0];
            end
            if gr_green(26)>0
                app.EditField_26.BackgroundColor = [0,1,0];
            end
            if gr_green(27)>0
                app.EditField_27.BackgroundColor = [0,1,0];
            end
            if gr_green(28)>0
                app.EditField_28.BackgroundColor = [0,1,0];
            end
            if gr_green(29)>0
                app.EditField_29.BackgroundColor = [0,1,0];
            end
            if gr_green(30)>0
                app.EditField_30.BackgroundColor = [0,1,0];
            end
            if gr_green(31)>0
                app.EditField_31.BackgroundColor = [0,1,0];
            end
            if gr_green(32)>0
                app.EditField_32.BackgroundColor = [0,1,0];
            end
            if gr_green(33)>0
                app.EditField_33.BackgroundColor = [0,1,0];
            end
            if gr_green(34)>0
                app.EditField_34.BackgroundColor = [0,1,0];
            end
            if gr_green(35)>0
                app.EditField_35.BackgroundColor = [0,1,0];
            end
            if gr_green(36)>0
                app.EditField_36.BackgroundColor = [0,1,0];
            end
            if gr_green(37)>0
                app.EditField_37.BackgroundColor = [0,1,0];
            end
            if gr_green(38)>0
                app.EditField_38.BackgroundColor = [0,1,0];
            end
            if gr_green(39)>0
                app.EditField_39.BackgroundColor = [0,1,0];
            end
            if gr_green(40)>0
                app.EditField_40.BackgroundColor = [0,1,0];
            end
            if gr_green(41)>0
                app.EditField_41.BackgroundColor = [0,1,0];
            end
            if gr_green(42)>0
                app.EditField_42.BackgroundColor = [0,1,0];
            end
            if gr_green(43)>0
                app.EditField_43.BackgroundColor = [0,1,0];
            end
            if gr_green(44)>0
                app.EditField_44.BackgroundColor = [0,1,0];
            end
            if gr_green(45)>0
                app.EditField_45.BackgroundColor = [0,1,0];
            end
            if gr_green(46)>0
                app.EditField_46.BackgroundColor = [0,1,0];
            end
            if gr_green(47)>0
                app.EditField_47.BackgroundColor = [0,1,0];
            end
            if gr_green(48)>0
                app.EditField_48.BackgroundColor = [0,1,0];
            end
            if gr_green(49)>0
                app.EditField_49.BackgroundColor = [0,1,0];
            end
            if gr_green(50)>0
                app.EditField_50.BackgroundColor = [0,1,0];
            end
            if gr_green(51)>0
                app.EditField_51.BackgroundColor = [0,1,0];
            end
            if gr_green(52)>0
                app.EditField_52.BackgroundColor = [0,1,0];
            end
            if gr_green(53)>0
                app.EditField_53.BackgroundColor = [0,1,0];
            end
            if gr_green(54)>0
                app.EditField_54.BackgroundColor = [0,1,0];
            end
            if gr_green(55)>0
                app.EditField_55.BackgroundColor = [0,1,0];
            end
            if gr_green(56)>0
                app.EditField_56.BackgroundColor = [0,1,0];
            end
            if gr_green(57)>0
                app.EditField_57.BackgroundColor = [0,1,0];
            end
            if gr_green(58)>0
                app.EditField_58.BackgroundColor = [0,1,0];
            end
            if gr_green(59)>0
                app.EditField_59.BackgroundColor = [0,1,0];
            end
            if gr_green(60)>0
                app.EditField_60.BackgroundColor = [0,1,0];
            end
            if gr_green(61)>0
                app.EditField_61.BackgroundColor = [0,1,0];
            end
            if gr_green(62)>0
                app.EditField_62.BackgroundColor = [0,1,0];
            end
            if gr_green(63)>0
                app.EditField_63.BackgroundColor = [0,1,0];
            end
            if gr_green(64)>0
                app.EditField_64.BackgroundColor = [0,1,0];
            end
            if gr_green(65)>0
                app.EditField_65.BackgroundColor = [0,1,0];
            end
            if gr_green(66)>0
                app.EditField_66.BackgroundColor = [0,1,0];
            end
            if gr_green(67)>0
                app.EditField_67.BackgroundColor = [0,1,0];
            end
            if gr_green(68)>0
                app.EditField_68.BackgroundColor = [0,1,0];
            end
            if gr_green(69)>0
                app.EditField_69.BackgroundColor = [0,1,0];
            end
            if gr_green(70)>0
                app.EditField_70.BackgroundColor = [0,1,0];
            end
            if gr_green(71)>0
                app.EditField_71.BackgroundColor = [0,1,0];
            end
            if gr_green(72)>0
                app.EditField_72.BackgroundColor = [0,1,0];
            end
            if gr_green(73)>0
                app.EditField_73.BackgroundColor = [0,1,0];
            end
            if gr_green(74)>0
                app.EditField_74.BackgroundColor = [0,1,0];
            end
            if gr_green(75)>0
                app.EditField_75.BackgroundColor = [0,1,0];
            end
            if gr_green(76)>0
                app.EditField_76.BackgroundColor = [0,1,0];
            end
            if gr_green(77)>0
                app.EditField_77.BackgroundColor = [0,1,0];
            end
            if gr_green(78)>0
                app.EditField_78.BackgroundColor = [0,1,0];
            end
            if gr_green(79)>0
                app.EditField_79.BackgroundColor = [0,1,0];
            end
            if gr_green(80)>0
                app.EditField_80.BackgroundColor = [0,1,0];
            end
            if gr_green(81)>0
                app.EditField_81.BackgroundColor = [0,1,0];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ExpertTab = uitab(app.TabGroup);
            app.ExpertTab.Title = 'Expert';
            app.InputYourSudokuPanel = uipanel(app.ExpertTab);
            app.InputYourSudokuPanel.Title = 'Input Your Sudoku';
            app.Panel = uipanel(app.InputYourSudokuPanel);
            app.GridLayout = uigridlayout(app.Panel);
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Limits = [0 9];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_10 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_10.Limits = [0 9];
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_19 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_19.Limits = [0 9];
            app.EditField_19.ValueChangedFcn = createCallbackFcn(app, @EditField_19ValueChanged, true);
            app.EditField_20 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_20.Limits = [0 9];
            app.EditField_20.ValueChangedFcn = createCallbackFcn(app, @EditField_20ValueChanged, true);
            app.EditField_11 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_11.Limits = [0 9];
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.Limits = [0 9];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3.Limits = [0 9];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_12 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_12.Limits = [0 9];
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_21 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_21.Limits = [0 9];
            app.EditField_21.ValueChangedFcn = createCallbackFcn(app, @EditField_21ValueChanged, true);
            app.Panel_2 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_2 = uigridlayout(app.Panel_2);
            app.EditField_48 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_48.Limits = [0 9];
            app.EditField_48.ValueChangedFcn = createCallbackFcn(app, @EditField_48ValueChanged, true);
            app.EditField_39 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_39.Limits = [0 9];
            app.EditField_39.ValueChangedFcn = createCallbackFcn(app, @EditField_39ValueChanged, true);
            app.EditField_30 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_30.Limits = [0 9];
            app.EditField_30.ValueChangedFcn = createCallbackFcn(app, @EditField_30ValueChanged, true);
            app.EditField_29 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_29.Limits = [0 9];
            app.EditField_29.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
            app.EditField_28 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_28.Limits = [0 9];
            app.EditField_28.ValueChangedFcn = createCallbackFcn(app, @EditField_28ValueChanged, true);
            app.EditField_37 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_37.Limits = [0 9];
            app.EditField_37.ValueChangedFcn = createCallbackFcn(app, @EditField_37ValueChanged, true);
            app.EditField_38 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_38.Limits = [0 9];
            app.EditField_38.ValueChangedFcn = createCallbackFcn(app, @EditField_38ValueChanged, true);
            app.EditField_47 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_47.Limits = [0 9];
            app.EditField_47.ValueChangedFcn = createCallbackFcn(app, @EditField_47ValueChanged, true);
            app.EditField_46 = uieditfield(app.GridLayout_2, 'numeric');
            app.EditField_46.Limits = [0 9];
            app.EditField_46.ValueChangedFcn = createCallbackFcn(app, @EditField_46ValueChanged, true);
            app.Panel_3 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_3 = uigridlayout(app.Panel_3);
            app.EditField_75 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_75.Limits = [0 9];
            app.EditField_75.ValueChangedFcn = createCallbackFcn(app, @EditField_75ValueChanged, true);
            app.EditField_66 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_66.Limits = [0 9];
            app.EditField_66.ValueChangedFcn = createCallbackFcn(app, @EditField_66ValueChanged, true);
            app.EditField_57 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_57.Limits = [0 9];
            app.EditField_57.ValueChangedFcn = createCallbackFcn(app, @EditField_57ValueChanged, true);
            app.EditField_56 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_56.Limits = [0 9];
            app.EditField_56.ValueChangedFcn = createCallbackFcn(app, @EditField_56ValueChanged, true);
            app.EditField_55 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_55.Limits = [0 9];
            app.EditField_55.ValueChangedFcn = createCallbackFcn(app, @EditField_55ValueChanged, true);
            app.EditField_64 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_64.Limits = [0 9];
            app.EditField_64.ValueChangedFcn = createCallbackFcn(app, @EditField_64ValueChanged, true);
            app.EditField_65 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_65.Limits = [0 9];
            app.EditField_65.ValueChangedFcn = createCallbackFcn(app, @EditField_65ValueChanged, true);
            app.EditField_74 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_74.Limits = [0 9];
            app.EditField_74.ValueChangedFcn = createCallbackFcn(app, @EditField_74ValueChanged, true);
            app.EditField_73 = uieditfield(app.GridLayout_3, 'numeric');
            app.EditField_73.Limits = [0 9];
            app.EditField_73.ValueChangedFcn = createCallbackFcn(app, @EditField_73ValueChanged, true);
            app.Panel_4 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_4 = uigridlayout(app.Panel_4);
            app.EditField_51 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_51.Limits = [0 9];
            app.EditField_51.ValueChangedFcn = createCallbackFcn(app, @EditField_51ValueChanged, true);
            app.EditField_42 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_42.Limits = [0 9];
            app.EditField_42.ValueChangedFcn = createCallbackFcn(app, @EditField_42ValueChanged, true);
            app.EditField_33 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_33.Limits = [0 9];
            app.EditField_33.ValueChangedFcn = createCallbackFcn(app, @EditField_33ValueChanged, true);
            app.EditField_32 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_32.Limits = [0 9];
            app.EditField_32.ValueChangedFcn = createCallbackFcn(app, @EditField_32ValueChanged, true);
            app.EditField_31 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_31.Limits = [0 9];
            app.EditField_31.ValueChangedFcn = createCallbackFcn(app, @EditField_31ValueChanged, true);
            app.EditField_40 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_40.Limits = [0 9];
            app.EditField_40.ValueChangedFcn = createCallbackFcn(app, @EditField_40ValueChanged, true);
            app.EditField_41 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_41.Limits = [0 9];
            app.EditField_41.ValueChangedFcn = createCallbackFcn(app, @EditField_41ValueChanged, true);
            app.EditField_50 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_50.Limits = [0 9];
            app.EditField_50.ValueChangedFcn = createCallbackFcn(app, @EditField_50ValueChanged, true);
            app.EditField_49 = uieditfield(app.GridLayout_4, 'numeric');
            app.EditField_49.Limits = [0 9];
            app.EditField_49.ValueChangedFcn = createCallbackFcn(app, @EditField_49ValueChanged, true);
            app.Panel_5 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_5 = uigridlayout(app.Panel_5);
            app.EditField_24 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_24.Limits = [0 9];
            app.EditField_24.ValueChangedFcn = createCallbackFcn(app, @EditField_24ValueChanged, true);
            app.EditField_15 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_15.Limits = [0 9];
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_6 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_6.Limits = [0 9];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_5 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_5.Limits = [0 9];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_4 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_4.Limits = [0 9];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_13 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_13.Limits = [0 9];
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_14 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_14.Limits = [0 9];
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_23 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_23.Limits = [0 9];
            app.EditField_23.ValueChangedFcn = createCallbackFcn(app, @EditField_23ValueChanged, true);
            app.EditField_22 = uieditfield(app.GridLayout_5, 'numeric');
            app.EditField_22.Limits = [0 9];
            app.EditField_22.ValueChangedFcn = createCallbackFcn(app, @EditField_22ValueChanged, true);
            app.Panel_6 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_6 = uigridlayout(app.Panel_6);
            app.EditField_78 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_78.Limits = [0 9];
            app.EditField_78.ValueChangedFcn = createCallbackFcn(app, @EditField_78ValueChanged, true);
            app.EditField_69 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_69.Limits = [0 9];
            app.EditField_69.ValueChangedFcn = createCallbackFcn(app, @EditField_69ValueChanged, true);
            app.EditField_60 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_60.Limits = [0 9];
            app.EditField_60.ValueChangedFcn = createCallbackFcn(app, @EditField_60ValueChanged, true);
            app.EditField_59 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_59.Limits = [0 9];
            app.EditField_59.ValueChangedFcn = createCallbackFcn(app, @EditField_59ValueChanged, true);
            app.EditField_58 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_58.Limits = [0 9];
            app.EditField_58.ValueChangedFcn = createCallbackFcn(app, @EditField_58ValueChanged, true);
            app.EditField_67 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_67.Limits = [0 9];
            app.EditField_67.ValueChangedFcn = createCallbackFcn(app, @EditField_67ValueChanged, true);
            app.EditField_68 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_68.Limits = [0 9];
            app.EditField_68.ValueChangedFcn = createCallbackFcn(app, @EditField_68ValueChanged, true);
            app.EditField_77 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_77.Limits = [0 9];
            app.EditField_77.ValueChangedFcn = createCallbackFcn(app, @EditField_77ValueChanged, true);
            app.EditField_76 = uieditfield(app.GridLayout_6, 'numeric');
            app.EditField_76.Limits = [0 9];
            app.EditField_76.ValueChangedFcn = createCallbackFcn(app, @EditField_76ValueChanged, true);
            app.Panel_7 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_7 = uigridlayout(app.Panel_7);
            app.EditField_54 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_54.Limits = [0 9];
            app.EditField_54.ValueChangedFcn = createCallbackFcn(app, @EditField_54ValueChanged, true);
            app.EditField_45 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_45.Limits = [0 9];
            app.EditField_45.ValueChangedFcn = createCallbackFcn(app, @EditField_45ValueChanged, true);
            app.EditField_36 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_36.Limits = [0 9];
            app.EditField_36.ValueChangedFcn = createCallbackFcn(app, @EditField_36ValueChanged, true);
            app.EditField_35 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_35.Limits = [0 9];
            app.EditField_35.ValueChangedFcn = createCallbackFcn(app, @EditField_35ValueChanged, true);
            app.EditField_34 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_34.Limits = [0 9];
            app.EditField_34.ValueChangedFcn = createCallbackFcn(app, @EditField_34ValueChanged, true);
            app.EditField_43 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_43.Limits = [0 9];
            app.EditField_43.ValueChangedFcn = createCallbackFcn(app, @EditField_43ValueChanged, true);
            app.EditField_44 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_44.Limits = [0 9];
            app.EditField_44.ValueChangedFcn = createCallbackFcn(app, @EditField_44ValueChanged, true);
            app.EditField_53 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_53.Limits = [0 9];
            app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @EditField_53ValueChanged, true);
            app.EditField_52 = uieditfield(app.GridLayout_7, 'numeric');
            app.EditField_52.Limits = [0 9];
            app.EditField_52.ValueChangedFcn = createCallbackFcn(app, @EditField_52ValueChanged, true);
            app.Panel_8 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_8 = uigridlayout(app.Panel_8);
            app.EditField_27 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_27.Limits = [0 9];
            app.EditField_27.ValueChangedFcn = createCallbackFcn(app, @EditField_27ValueChanged, true);
            app.EditField_18 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_18.Limits = [0 9];
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @EditField_18ValueChanged, true);
            app.EditField_9 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_9.Limits = [0 9];
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_8 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_8.Limits = [0 9];
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_7 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_7.Limits = [0 9];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_16 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_16.Limits = [0 9];
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.EditField_17 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_17.Limits = [0 9];
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_17ValueChanged, true);
            app.EditField_26 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_26.Limits = [0 9];
            app.EditField_26.ValueChangedFcn = createCallbackFcn(app, @EditField_26ValueChanged, true);
            app.EditField_25 = uieditfield(app.GridLayout_8, 'numeric');
            app.EditField_25.Limits = [0 9];
            app.EditField_25.ValueChangedFcn = createCallbackFcn(app, @EditField_25ValueChanged, true);
            app.Panel_9 = uipanel(app.InputYourSudokuPanel);
            app.GridLayout_9 = uigridlayout(app.Panel_9);
            app.EditField_81 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_81.Limits = [0 9];
            app.EditField_81.ValueChangedFcn = createCallbackFcn(app, @EditField_81ValueChanged, true);
            app.EditField_72 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_72.Limits = [0 9];
            app.EditField_72.ValueChangedFcn = createCallbackFcn(app, @EditField_72ValueChanged, true);
            app.EditField_63 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_63.Limits = [0 9];
            app.EditField_63.ValueChangedFcn = createCallbackFcn(app, @EditField_63ValueChanged, true);
            app.EditField_62 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_62.Limits = [0 9];
            app.EditField_62.ValueChangedFcn = createCallbackFcn(app, @EditField_62ValueChanged, true);
            app.EditField_61 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_61.Limits = [0 9];
            app.EditField_61.ValueChangedFcn = createCallbackFcn(app, @EditField_61ValueChanged, true);
            app.EditField_70 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_70.Limits = [0 9];
            app.EditField_70.ValueChangedFcn = createCallbackFcn(app, @EditField_70ValueChanged, true);
            app.EditField_71 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_71.Limits = [0 9];
            app.EditField_71.ValueChangedFcn = createCallbackFcn(app, @EditField_71ValueChanged, true);
            app.EditField_80 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_80.Limits = [0 9];
            app.EditField_80.ValueChangedFcn = createCallbackFcn(app, @EditField_80ValueChanged, true);
            app.EditField_79 = uieditfield(app.GridLayout_9, 'numeric');
            app.EditField_79.Limits = [0 9];
            app.EditField_79.ValueChangedFcn = createCallbackFcn(app, @EditField_79ValueChanged, true);
            app.SOLVEButton = uibutton(app.ExpertTab, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Text = 'SOLVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sudoku_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end